classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        TabGroup  matlab.ui.container.TabGroup
        Tab       matlab.ui.container.Tab
        Tab2      matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % initiate the framework.
obj = matpigui ( 'buttonHeight', 0.1);
% Create the 3 tabs:
obj.addTab ( 'Login Page', 0.2 );
obj.addTab ( 'Work Flow', 0.2, 'enable', 'off' );
% set up variables for the ui controls
posP = 'pos'; posV = [0.35 0.6 0.4 0.075];
titleP = 'title'; titleV = 'Login Name: ';
% add the UIC for the login page
obj.addUIC ( 'Login Page', 'editLogin','edit', posP, posV, titleP, titleV );
posV = [0.35 0.4 0.4 0.075];
titleV = 'Login Name: ';
% add the password entry:
obj.addUIC('Login Page','editPass','protectedEdit', titleP, titleV, posP, posV );
% add the login button:
obj.addUIC ( 'Login Page', 'login', 'pushButton', 'pos', [0.35 0.1 0.4 0.125], ...
             'string', 'Login', 'Callback', @(a,b)obj.enable ( 'Work Flow' ) );
% split the work flow tab into 4 sub tabs
obj.split ( 'Work Flow', [2 2], { 'A' 'B' 'C' 'D' } );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end